classdef fluid_mechanics < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        DifferentViscositiesLabel       matlab.ui.control.Label
        DifferentPressureGradientsLabel  matlab.ui.control.Label
        DifferentVelocityLabel          matlab.ui.control.Label
        VelocityProfileBetweenTwoParallelPlatesLabel  matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout4_5                   matlab.ui.container.GridLayout
        GridLayout6_3                   matlab.ui.container.GridLayout
        GridLayout8_6                   matlab.ui.container.GridLayout
        d_PressG_PlateDistance          matlab.ui.control.NumericEditField
        PlateDistancemEditFieldLabel_3  matlab.ui.control.Label
        GridLayout8_5                   matlab.ui.container.GridLayout
        d_PressG_muVal                  matlab.ui.control.NumericEditField
        pxLabel_4                       matlab.ui.control.Label
        d_PressG_dpdxVal                matlab.ui.control.NumericEditField
        pxLabel_3                       matlab.ui.control.Label
        GridLayout7_3                   matlab.ui.container.GridLayout
        d_PressG_ClearBtn               matlab.ui.control.Button
        d_PressG_ToggleInfoBtn          matlab.ui.control.Button
        d_PressG_AddBtn                 matlab.ui.control.Button
        d_PressG_UIAxes                 matlab.ui.control.UIAxes
        GridLayout4_4                   matlab.ui.container.GridLayout
        GridLayout6_2                   matlab.ui.container.GridLayout
        GridLayout8_4                   matlab.ui.container.GridLayout
        d_Vis_PlateDistance             matlab.ui.control.NumericEditField
        PlateDistancemEditFieldLabel_2  matlab.ui.control.Label
        GridLayout8_3                   matlab.ui.container.GridLayout
        d_Vis_muVal                     matlab.ui.control.NumericEditField
        pxLabel_2                       matlab.ui.control.Label
        d_Vis_dpdxVal                   matlab.ui.control.NumericEditField
        pxLabel                         matlab.ui.control.Label
        GridLayout7_2                   matlab.ui.container.GridLayout
        d_Vis_ClearBtn                  matlab.ui.control.Button
        d_Vis_ToggleInfoBtn             matlab.ui.control.Button
        d_Vis_AddBtn                    matlab.ui.control.Button
        d_Vis_UIAxes                    matlab.ui.control.UIAxes
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout8_2                   matlab.ui.container.GridLayout
        d_Vel_PlateDistance             matlab.ui.control.NumericEditField
        PlateDistancemEditFieldLabel    matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        d_Vel_vVal                      matlab.ui.control.NumericEditField
        VelocityValuemsecEditFieldLabel  matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        d_Vel_ClearBtn                  matlab.ui.control.Button
        d_Vel_ToggleInfoBtn             matlab.ui.control.Button
        d_Vel_AddBtn                    matlab.ui.control.Button
        d_Vel_UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function color = generateRandomColor(comp)
            R = rand();
            G = rand();
            B = rand();

            color = [R, G, B];
        end
        
        function res = toggleLegend(comp, lg)
            if lg.Visible == 'on'
                lg.Visible = 'off';
            else
                lg.Visible = 'on';
            end
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: d_Vel_AddBtn
        function addPlotToVelocityAxes(comp, event)
            color = generateRandomColor(comp);
            h = 0.2;
            y = linspace(-h, h, 200);
            plateDistance = 2 * h;
            velocity_val  = comp.d_Vel_vVal.Value;

            hold(comp.d_Vel_UIAxes, 'on');

            prof_u = velocity_val * (y + 0.2) / (plateDistance);
            plot(comp.d_Vel_UIAxes, prof_u, y, 'Color', color, 'LineWidth', 2, 'DisplayName', sprintf('$V = %d \\frac{m}{sec}$', velocity_val));

            lg = legend(comp.d_Vel_UIAxes, 'Interpreter', 'latex');
            lg.Location = "Best";
            lg.FontSize = 10;

            grid(comp.d_Vel_UIAxes, 'on');
            hold(comp.d_Vel_UIAxes, 'off');
        end

        % Button pushed function: d_Vis_AddBtn
        function addPlotToViscositiesAxes(comp, event)
            color = generateRandomColor(comp);
            h = 0.2;
            y = linspace(-h, h, 200);
            plateDistance = 2 * h;
            mu_val  = comp.d_Vis_muVal.Value;
            dp_dx_val = comp.d_Vis_dpdxVal.Value;

            hold(comp.d_Vis_UIAxes, 'on');

            prof_u = (1 / (2 * mu_val)) * dp_dx_val * (y.^2 - (plateDistance / 2)^2);
            plot(comp.d_Vis_UIAxes, prof_u, y, 'Color', color, 'LineWidth', 2, 'DisplayName', sprintf('$\\mu = %.1e \\frac{kg}{m.sec}$', mu_val));

            lg = legend(comp.d_Vis_UIAxes, 'Interpreter', 'latex');
            lg.Location = "Best";
            lg.FontSize = 10;

            grid(comp.d_Vis_UIAxes, 'on');
            hold(comp.d_Vis_UIAxes, 'off');
        end

        % Button pushed function: d_PressG_AddBtn
        function addPlotToPressGAxes(comp, event)
            color = generateRandomColor(comp);
            h = 0.2;
            y = linspace(-h, h, 200);
            plateDistance = 2 * h;
            mu_val  = comp.d_PressG_muVal.Value;
            dp_dx_val = comp.d_PressG_dpdxVal.Value;

            hold(comp.d_PressG_UIAxes, 'on');

            prof_u = (1 / (2 * mu_val)) * dp_dx_val * (y.^2 - (plateDistance / 2)^2);
            plot(comp.d_PressG_UIAxes, prof_u, y, 'Color', color, 'LineWidth', 2, 'DisplayName', sprintf('$\\frac{\\partial p}{\\partial x}$ = %.2f', dp_dx_val));

            lg = legend(comp.d_PressG_UIAxes, 'Interpreter', 'latex');
            lg.Location = "Best";
            lg.FontSize = 10;

            grid(comp.d_PressG_UIAxes, 'on');
            hold(comp.d_PressG_UIAxes, 'off');
        end

        % Button pushed function: d_Vel_ClearBtn
        function clearVelocityAxes(comp, event)
            cla(comp.d_Vel_UIAxes);
            legend(comp.d_Vel_UIAxes, 'off');
        end

        % Button pushed function: d_Vis_ClearBtn
        function clearViscositiesAxes(comp, event)
            cla(comp.d_Vis_UIAxes);
            legend(comp.d_Vis_UIAxes, 'off');
        end

        % Button pushed function: d_PressG_ClearBtn
        function clearPressGAxes(comp, event)
            cla(comp.d_PressG_UIAxes);
            legend(comp.d_PressG_UIAxes, 'off');
        end

        % Button pushed function: d_Vel_ToggleInfoBtn
        function toggleInfoVelocityAxes(comp, event)
            toggleLegend(comp, legend(comp.d_Vel_UIAxes));
        end

        % Button pushed function: d_Vis_ToggleInfoBtn
        function toggleInfoViscositiesAxes(comp, event)
            toggleLegend(comp, legend(comp.d_Vis_UIAxes));
        end

        % Button pushed function: d_PressG_ToggleInfoBtn
        function toggleInfoPressGAxes(comp, event)
            toggleLegend(comp, legend(comp.d_PressG_UIAxes));
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1736 893];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout4 = uigridlayout(comp.GridLayout2);
            comp.d_Vel_UIAxes = uiaxes(comp.GridLayout4);
            xlabel(comp.d_Vel_UIAxes, 'u(y)')
            ylabel(comp.d_Vel_UIAxes, 'y')
            zlabel(comp.d_Vel_UIAxes, 'Z')
            comp.GridLayout6 = uigridlayout(comp.GridLayout4);
            comp.GridLayout7 = uigridlayout(comp.GridLayout6);
            comp.d_Vel_AddBtn = uibutton(comp.GridLayout7, 'push');
            comp.d_Vel_AddBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addPlotToVelocityAxes, true);
            comp.d_Vel_AddBtn.Text = 'Add';
            comp.d_Vel_ToggleInfoBtn = uibutton(comp.GridLayout7, 'push');
            comp.d_Vel_ToggleInfoBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @toggleInfoVelocityAxes, true);
            comp.d_Vel_ToggleInfoBtn.Text = 'Toggle Info';
            comp.d_Vel_ClearBtn = uibutton(comp.GridLayout7, 'push');
            comp.d_Vel_ClearBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @clearVelocityAxes, true);
            comp.d_Vel_ClearBtn.Text = 'Clear';
            comp.GridLayout8 = uigridlayout(comp.GridLayout6);
            comp.VelocityValuemsecEditFieldLabel = uilabel(comp.GridLayout8);
            comp.VelocityValuemsecEditFieldLabel.Text = 'Velocity Value(m.sec)';
            comp.d_Vel_vVal = uieditfield(comp.GridLayout8, 'numeric');
            comp.GridLayout8_2 = uigridlayout(comp.GridLayout6);
            comp.PlateDistancemEditFieldLabel = uilabel(comp.GridLayout8_2);
            comp.PlateDistancemEditFieldLabel.Text = 'Plate Distance(m)';
            comp.d_Vel_PlateDistance = uieditfield(comp.GridLayout8_2, 'numeric');
            comp.d_Vel_PlateDistance.Editable = 'off';
            comp.d_Vel_PlateDistance.Tooltip = {'[-0.2, 0.2]'};
            comp.d_Vel_PlateDistance.Value = 0.4;
            comp.GridLayout4_4 = uigridlayout(comp.GridLayout2);
            comp.d_Vis_UIAxes = uiaxes(comp.GridLayout4_4);
            xlabel(comp.d_Vis_UIAxes, 'u(y)')
            ylabel(comp.d_Vis_UIAxes, 'y')
            zlabel(comp.d_Vis_UIAxes, 'Z')
            comp.GridLayout6_2 = uigridlayout(comp.GridLayout4_4);
            comp.GridLayout7_2 = uigridlayout(comp.GridLayout6_2);
            comp.d_Vis_AddBtn = uibutton(comp.GridLayout7_2, 'push');
            comp.d_Vis_AddBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addPlotToViscositiesAxes, true);
            comp.d_Vis_AddBtn.Text = 'Add';
            comp.d_Vis_ToggleInfoBtn = uibutton(comp.GridLayout7_2, 'push');
            comp.d_Vis_ToggleInfoBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @toggleInfoViscositiesAxes, true);
            comp.d_Vis_ToggleInfoBtn.Text = 'Toggle Info';
            comp.d_Vis_ClearBtn = uibutton(comp.GridLayout7_2, 'push');
            comp.d_Vis_ClearBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @clearViscositiesAxes, true);
            comp.d_Vis_ClearBtn.Text = 'Clear';
            comp.GridLayout8_3 = uigridlayout(comp.GridLayout6_2);
            comp.pxLabel = uilabel(comp.GridLayout8_3);
            comp.pxLabel.Text = '∂p/∂x';
            comp.d_Vis_dpdxVal = uieditfield(comp.GridLayout8_3, 'numeric');
            comp.pxLabel_2 = uilabel(comp.GridLayout8_3);
            comp.pxLabel_2.Text = 'μ(kg/m.s)';
            comp.d_Vis_muVal = uieditfield(comp.GridLayout8_3, 'numeric');
            comp.GridLayout8_4 = uigridlayout(comp.GridLayout6_2);
            comp.PlateDistancemEditFieldLabel_2 = uilabel(comp.GridLayout8_4);
            comp.PlateDistancemEditFieldLabel_2.Text = 'Plate Distance(m)';
            comp.d_Vis_PlateDistance = uieditfield(comp.GridLayout8_4, 'numeric');
            comp.d_Vis_PlateDistance.Editable = 'off';
            comp.d_Vis_PlateDistance.Tooltip = {'[-0.2, 0.2]'};
            comp.d_Vis_PlateDistance.Value = 0.4;
            comp.GridLayout4_5 = uigridlayout(comp.GridLayout2);
            comp.d_PressG_UIAxes = uiaxes(comp.GridLayout4_5);
            xlabel(comp.d_PressG_UIAxes, 'u(y)')
            ylabel(comp.d_PressG_UIAxes, 'y')
            zlabel(comp.d_PressG_UIAxes, 'Z')
            comp.GridLayout6_3 = uigridlayout(comp.GridLayout4_5);
            comp.GridLayout7_3 = uigridlayout(comp.GridLayout6_3);
            comp.d_PressG_AddBtn = uibutton(comp.GridLayout7_3, 'push');
            comp.d_PressG_AddBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addPlotToPressGAxes, true);
            comp.d_PressG_AddBtn.Text = 'Add';
            comp.d_PressG_ToggleInfoBtn = uibutton(comp.GridLayout7_3, 'push');
            comp.d_PressG_ToggleInfoBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @toggleInfoPressGAxes, true);
            comp.d_PressG_ToggleInfoBtn.Text = 'Toggle Info';
            comp.d_PressG_ClearBtn = uibutton(comp.GridLayout7_3, 'push');
            comp.d_PressG_ClearBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @clearPressGAxes, true);
            comp.d_PressG_ClearBtn.Text = 'Clear';
            comp.GridLayout8_5 = uigridlayout(comp.GridLayout6_3);
            comp.pxLabel_3 = uilabel(comp.GridLayout8_5);
            comp.pxLabel_3.Text = '∂p/∂x';
            comp.d_PressG_dpdxVal = uieditfield(comp.GridLayout8_5, 'numeric');
            comp.pxLabel_4 = uilabel(comp.GridLayout8_5);
            comp.pxLabel_4.Text = 'μ(kg/m.s)';
            comp.d_PressG_muVal = uieditfield(comp.GridLayout8_5, 'numeric');
            comp.GridLayout8_6 = uigridlayout(comp.GridLayout6_3);
            comp.PlateDistancemEditFieldLabel_3 = uilabel(comp.GridLayout8_6);
            comp.PlateDistancemEditFieldLabel_3.Text = 'Plate Distance(m)';
            comp.d_PressG_PlateDistance = uieditfield(comp.GridLayout8_6, 'numeric');
            comp.d_PressG_PlateDistance.Editable = 'off';
            comp.d_PressG_PlateDistance.Tooltip = {'[-0.2, 0.2]'};
            comp.d_PressG_PlateDistance.Value = 0.4;
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.VelocityProfileBetweenTwoParallelPlatesLabel = uilabel(comp.GridLayout3);
            comp.VelocityProfileBetweenTwoParallelPlatesLabel.Text = 'Velocity Profile Between Two Parallel Plates';
            comp.GridLayout5 = uigridlayout(comp.GridLayout3);
            comp.DifferentVelocityLabel = uilabel(comp.GridLayout5);
            comp.DifferentVelocityLabel.Text = 'Different Velocity';
            comp.DifferentPressureGradientsLabel = uilabel(comp.GridLayout5);
            comp.DifferentPressureGradientsLabel.Text = 'Different  Pressure Gradients';
            comp.DifferentViscositiesLabel = uilabel(comp.GridLayout5);
            comp.DifferentViscositiesLabel.Text = 'Different Viscosities';
        end
    end
end