classdef venturi_tube < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        ThecrosssectionalareaoftheVenturitubeisassumedtobecircularLabel  matlab.ui.control.Label
        GridLayout4_2                  matlab.ui.container.GridLayout
        clearAllPlot                   matlab.ui.control.Button
        runPlotBtn                     matlab.ui.control.Button
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        atmospheric_pressure_at_entry  matlab.ui.control.NumericEditField
        AtmosphericpressureatentryPaEditFieldLabel  matlab.ui.control.Label
        diameter_at_exit               matlab.ui.control.NumericEditField
        DiameteratexitmLabel           matlab.ui.control.Label
        diameter_at_throat             matlab.ui.control.NumericEditField
        DiameteratthroatmLabel         matlab.ui.control.Label
        diameter_at_entry              matlab.ui.control.NumericEditField
        DiameteratentrymLabel          matlab.ui.control.Label
        volumetric_flow_rate           matlab.ui.control.NumericEditField
        VolumetricflowratemsEditFieldLabel  matlab.ui.control.Label
        density_of_air                 matlab.ui.control.NumericEditField
        DensityofairkgmEditFieldLabel  matlab.ui.control.Label
        VentureTubeinformationLabel    matlab.ui.control.Label
        UIAxes_P_P                     matlab.ui.control.UIAxes
        UIAxes_V_P                     matlab.ui.control.UIAxes
        UIAxes_V_T                     matlab.ui.control.UIAxes
        AnalysisofVenturiTubeVelocityandPressureProfilesLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runPlotBtn
        function runPlotBtnButtonPushed(comp, event)
            % Parameters
            rho = comp.density_of_air.Value; % Density of air in kg/m^3
            Q = comp.volumetric_flow_rate.Value; % Volumetric flow rate in m^3/s
            
            % Venturi Dimensions
            D1 = comp.diameter_at_entry.Value; % Diameter at entry in meters
            D2 = comp.diameter_at_throat.Value; % Diameter at throat in meters
            D3 = comp.diameter_at_exit.Value; % Diameter at exit in meters
            
            % Calculate Areas
            A1 = pi * (D1 / 2)^2;
            A2 = pi * (D2 / 2)^2;
            A3 = pi * (D3 / 2)^2;
            
            % Calculate Velocities
            V1 = Q / A1;
            V2 = Q / A2;
            V3 = Q / A3;
            
            % Calculate Pressures using Bernoulli's equation
            P1 = comp.atmospheric_pressure_at_entry.Value; % Atmospheric pressure at entry
            P2 = P1 + 0.5 * rho * (V1^2 - V2^2);
            P3 = P1 + 0.5 * rho * (V1^2 - V3^2);

            % Plotting the Venturi Tube
            hold(comp.UIAxes_V_T, 'on');
            plot(comp.UIAxes_V_T,[0, 1], [D1/2, D2/2], 'k', 'LineWidth', 2); % Converging section
            plot(comp.UIAxes_V_T,[0, 1], [-D1/2, -D2/2], 'k', 'LineWidth', 2); % Converging section
            plot(comp.UIAxes_V_T,[1, 2], [D2/2, D2/2], 'k', 'LineWidth', 2); % Throat
            plot(comp.UIAxes_V_T,[1, 2], [-D2/2, -D2/2], 'k', 'LineWidth', 2); % Throat
            plot(comp.UIAxes_V_T,[2, 3], [D2/2, D3/2], 'k', 'LineWidth', 2); % Diverging section
            plot(comp.UIAxes_V_T,[2, 3], [-D2/2, -D3/2], 'k', 'LineWidth', 2); % Diverging section
            hold(comp.UIAxes_V_T, 'off');

            % Plotting Velocity
            plot(comp.UIAxes_V_P, [0, 1, 2, 3], [V1, V2, V2, V3], 'b-o');

            % Plotting Pressure profiles
            plot(comp.UIAxes_P_P, [0, 1, 2, 3], [P1, P2, P2, P3], 'r-o');

            % Adding text annotations TO Plotting Velocity
            text(comp.UIAxes_V_P, 0.2, V1 + (V1/11), ['Entry V: ' num2str(V1, '%.2f') ' m/s'], 'FontSize', 12, 'Interpreter', 'latex');
            text(comp.UIAxes_V_P, 1.2, V2 - (V2/11), ['Throat V: ' num2str(V2, '%.2f') ' m/s'], 'FontSize', 12, 'Interpreter', 'latex');
            text(comp.UIAxes_V_P, 2.2, V3, ['Exit V: ' num2str(V3, '%.2f') ' m/s'], 'FontSize', 12, 'Interpreter', 'latex');
            % Adding text annotations TO Plotting Velocity
            text(comp.UIAxes_P_P, 0.2, P1, ['Entry P: ' num2str(P1, '%.2f') ' Pa'], 'FontSize', 12, 'Interpreter', 'latex');
            text(comp.UIAxes_P_P, 1.2, P2 + (P1/100), ['Throat P: ' num2str(P2, '%.2f') ' Pa'], 'FontSize', 12, 'Interpreter', 'latex');
            text(comp.UIAxes_P_P, 2.2, P3, ['Exit P: ' num2str(P3, '%.2f') ' Pa'], 'FontSize', 12, 'Interpreter', 'latex');
        end

        % Button pushed function: clearAllPlot
        function clearAllPlotButtonPushed(comp, event)
            cla(comp.UIAxes_V_T);
            legend(comp.UIAxes_V_T, 'off');
            cla(comp.UIAxes_V_P);
            legend(comp.UIAxes_V_P, 'off');            
            cla(comp.UIAxes_P_P);
            legend(comp.UIAxes_P_P, 'off');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1272 723];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.AnalysisofVenturiTubeVelocityandPressureProfilesLabel = uilabel(comp.GridLayout);
            comp.AnalysisofVenturiTubeVelocityandPressureProfilesLabel.Text = 'Analysis of Venturi Tube: Velocity and Pressure Profiles';
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.UIAxes_V_T = uiaxes(comp.GridLayout2);
            title(comp.UIAxes_V_T, 'Venturi Tube')
            xlabel(comp.UIAxes_V_T, 'Length (m)')
            ylabel(comp.UIAxes_V_T, 'Diameter (m)')
            zlabel(comp.UIAxes_V_T, 'Z')
            comp.UIAxes_V_P = uiaxes(comp.GridLayout2);
            title(comp.UIAxes_V_P, 'Velocity Profile in Venturi Tube')
            xlabel(comp.UIAxes_V_P, 'Position along the Venturi Tube (m)')
            ylabel(comp.UIAxes_V_P, 'Velocity (m/s)')
            zlabel(comp.UIAxes_V_P, 'Z')
            comp.UIAxes_P_P = uiaxes(comp.GridLayout2);
            title(comp.UIAxes_P_P, 'Pressure Profile in Venturi Tube')
            xlabel(comp.UIAxes_P_P, 'Position along the Venturi Tube (m)')
            ylabel(comp.UIAxes_P_P, 'Pressure (Pa)')
            zlabel(comp.UIAxes_P_P, 'Z')
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.GridLayout5 = uigridlayout(comp.GridLayout3);
            comp.VentureTubeinformationLabel = uilabel(comp.GridLayout5);
            comp.VentureTubeinformationLabel.Text = 'Venture Tube information';
            comp.GridLayout6 = uigridlayout(comp.GridLayout5);
            comp.DensityofairkgmEditFieldLabel = uilabel(comp.GridLayout6);
            comp.DensityofairkgmEditFieldLabel.Text = 'Density of air (kg/m³)';
            comp.density_of_air = uieditfield(comp.GridLayout6, 'numeric');
            comp.density_of_air.Value = 1.225;
            comp.VolumetricflowratemsEditFieldLabel = uilabel(comp.GridLayout6);
            comp.VolumetricflowratemsEditFieldLabel.Text = 'Volumetric flow rate (m³/s)';
            comp.volumetric_flow_rate = uieditfield(comp.GridLayout6, 'numeric');
            comp.volumetric_flow_rate.Value = 1;
            comp.DiameteratentrymLabel = uilabel(comp.GridLayout6);
            comp.DiameteratentrymLabel.Text = 'Diameter at entry(m)';
            comp.diameter_at_entry = uieditfield(comp.GridLayout6, 'numeric');
            comp.diameter_at_entry.Value = 0.2;
            comp.DiameteratthroatmLabel = uilabel(comp.GridLayout6);
            comp.DiameteratthroatmLabel.Text = 'Diameter at throat(m)';
            comp.diameter_at_throat = uieditfield(comp.GridLayout6, 'numeric');
            comp.diameter_at_throat.Value = 0.1;
            comp.DiameteratexitmLabel = uilabel(comp.GridLayout6);
            comp.DiameteratexitmLabel.Text = 'Diameter at exit(m)';
            comp.diameter_at_exit = uieditfield(comp.GridLayout6, 'numeric');
            comp.diameter_at_exit.Value = 0.15;
            comp.AtmosphericpressureatentryPaEditFieldLabel = uilabel(comp.GridLayout6);
            comp.AtmosphericpressureatentryPaEditFieldLabel.Text = 'Atmospheric pressure at entry(Pa)';
            comp.atmospheric_pressure_at_entry = uieditfield(comp.GridLayout6, 'numeric');
            comp.atmospheric_pressure_at_entry.Value = 101325;
            comp.GridLayout4_2 = uigridlayout(comp.GridLayout3);
            comp.runPlotBtn = uibutton(comp.GridLayout4_2, 'push');
            comp.runPlotBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @runPlotBtnButtonPushed, true);
            comp.runPlotBtn.Text = 'Run';
            comp.clearAllPlot = uibutton(comp.GridLayout4_2, 'push');
            comp.clearAllPlot.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @clearAllPlotButtonPushed, true);
            comp.clearAllPlot.Text = 'Clear';
            comp.ThecrosssectionalareaoftheVenturitubeisassumedtobecircularLabel = uilabel(comp.GridLayout3);
            comp.ThecrosssectionalareaoftheVenturitubeisassumedtobecircularLabel.Text = 'The cross-sectional area of the Venturi tube is assumed to be circular.';
        end
    end
end