classdef pr_modeling_veh < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout4_2                  matlab.ui.container.GridLayout
        clearAllPlot                   matlab.ui.control.Button
        runPlotBtn                     matlab.ui.control.Button
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        BumpInformationLabel           matlab.ui.control.Label
        info_simulation_time           matlab.ui.control.NumericEditField
        SimulationTimesEditFieldLabel  matlab.ui.control.Label
        info_v_m_s                     matlab.ui.control.NumericEditField
        VCarkmhEditFieldLabel          matlab.ui.control.Label
        info_start_distance            matlab.ui.control.NumericEditField
        DistancefrontwheeltobumpmEditFieldLabel  matlab.ui.control.Label
        info_d                         matlab.ui.control.NumericEditField
        DistancebetweenthewheelsmEditFieldLabel  matlab.ui.control.Label
        info_c                         matlab.ui.control.NumericEditField
        HeightmEditFieldLabel          matlab.ui.control.Label
        info_c2                        matlab.ui.control.NumericEditField
        CRearNsmEditField_2Label       matlab.ui.control.Label
        info_b_bump                    matlab.ui.control.NumericEditField
        SmallerbasemEditFieldLabel     matlab.ui.control.Label
        info_c1                        matlab.ui.control.NumericEditField
        CFrontNsmEditFieldLabel        matlab.ui.control.Label
        info_a                         matlab.ui.control.NumericEditField
        LargerbasemEditFieldLabel      matlab.ui.control.Label
        info_k2                        matlab.ui.control.NumericEditField
        KRearNmEditFieldLabel          matlab.ui.control.Label
        info_k1                        matlab.ui.control.NumericEditField
        KFrontNmEditFieldLabel         matlab.ui.control.Label
        info_I                         matlab.ui.control.NumericEditField
        MomentofinertiakgmLabel        matlab.ui.control.Label
        info_m                         matlab.ui.control.NumericEditField
        MassofthecarLabel              matlab.ui.control.Label
        SimulationinformationLabel     matlab.ui.control.Label
        UIAxes_V_T                     matlab.ui.control.UIAxes
        UIAxes_A_T                     matlab.ui.control.UIAxes
        UIAxes_V_H                     matlab.ui.control.UIAxes
        MSVSTSB                        matlab.ui.control.Label
    end

    
    methods (Access = public)
        function color = getRandomColor(comp)
            R = rand();
            G = rand();
            B = rand();

            color = [R, G, B];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runPlotBtn
        function runPlotBtnButtonPushed(comp, event)
            % Parameters
            m = comp.info_m.Value;                  % Mass of the car body (kg)
            k1 = comp.info_k1.Value;                % Suspension stiffness front (N/m)
            k2 = comp.info_k2.Value;                % Suspension stiffness rear (N/m)
            c1 = comp.info_c1.Value;                 % Suspension damping coefficient front (N.s/m)
            c2 = comp.info_c2.Value;                 % Suspension damping coefficient rear (N.s/m)
            d = comp.info_d.Value;                   % Distance between the wheels (m)
            b = d / 2;                 % Half of the distance between the wheels (m)
            v_m_s = comp.info_v_m_s.Value;                % Speed of the car (km/h)
            v = v_m_s * 1000 / 3600;    % Speed of the car (m/s)
            I = comp.info_I.Value;                  % Moment of inertia (kg.m^2)
            
            % Trapezoidal bump parameters
            a = comp.info_a.Value;                           % Larger base (m)
            b_bump = comp.info_b_bump.Value;                      % Smaller base (m)
            c = comp.info_c.Value;                         % Height (m)
            start_distance = comp.info_start_distance.Value;              % Distance from the car's front wheel to the bump start (m)
            start_time = start_distance / v; % Time when the front wheel hits the bump
            
            % Time vector
            s_time = comp.info_simulation_time.Value;
            t = 0:0.01:s_time;
            % Road bump input function for front and rear wheels
            y_front = @(t) (t >= start_time & t < start_time+a/(2*v)) .* (2*c/a) .* (v*t - start_time*v) + ...
                           (t >= start_time+a/(2*v) & t < start_time+(a-b_bump)/(2*v)) .* c + ...
                           (t >= start_time+(a-b_bump)/(2*v) & t < start_time+a/(2*v)) .* (2*c/a) .* (start_time*v - v*t + a/2) + ...
                           (t >= start_time+a/(2*v) & t < start_time+a/v) .* (-2*c/a) .* (v*t - start_time*v - a/2) + ...
                           (t >= start_time+a/v) * 0;
            
            y_rear = @(t) (t >= start_time+d/v & t < start_time+(a+d)/(2*v)) .* (2*c/a) .* (v*(t - start_time - d/v)) + ...
                           (t >= start_time+(a+d)/(2*v) & t < start_time+(a-b_bump+d)/(2*v)) .* c + ...
                           (t >= start_time+(a-b_bump+d)/(2*v) & t < start_time+(a+d)/(2*v)) .* (2*c/a) .* ((start_time + d/v)*v - v*t + a/2) + ...
                           (t >= start_time+(a+d)/(2*v) & t < start_time+(a+d)/v) .* (-2*c/a) .* (v*(t - start_time - d/v) - a/2) + ...
                           (t >= start_time+(a+d)/v) * 0;
            
            % ODE system
            ode = @(t, X) [X(2);
                           (-c1 * (X(2) + b*X(4) - y_front(t)) - k1 * (X(1) + b*X(3) - y_front(t)) ...
                           - c2 * (X(2) - b*X(4) - y_rear(t)) - k2 * (X(1) - b*X(3) - y_rear(t))) / m;
                           X(4);
                           (-c1 * b * (X(2) + b*X(4) - y_front(t)) - k1 * b * (X(1) + b*X(3) - y_front(t)) ...
                           + c2 * b * (X(2) - b*X(4) - y_rear(t)) + k2 * b * (X(1) - b*X(3) - y_rear(t))) / I];
            
            % Initial conditions
            X0 = [0; 0; 0; 0];
            
            % Solve ODE
            [T, X] = ode45(ode, t, X0);
            
            % Convert pitch angle to degrees
            pitch_angle_deg = rad2deg(X(:,3));
            
            % Plot results
            color = getRandomColor(comp);

            hold(comp.UIAxes_V_T, 'on');
            hold(comp.UIAxes_A_T, 'on');

            plot(comp.UIAxes_V_T, T, X(:,1), 'Color', color);
            plot(comp.UIAxes_A_T, T, pitch_angle_deg, 'Color', color);

            % Animation
            axis(comp.UIAxes_V_H, 'equal');
            hold(comp.UIAxes_V_H, 'on');
            grid(comp.UIAxes_V_H, 'on');
            
            plot(comp.UIAxes_V_H, [-5, 55], [0, 0], 'k', 'LineWidth', 2); % Road
            car_length = 5;
            car_width = 1.5;
            car = patch(comp.UIAxes_V_H, [-car_length/2, car_length/2, car_length/2, -car_length/2], ...
                        [-car_width/2, -car_width/2, car_width/2, car_width/2], color); % Car body
            front_wheel = plot(comp.UIAxes_V_H, 0, 0, 'ko', 'MarkerSize', 10, 'MarkerFaceColor', 'k'); % Front wheel
            rear_wheel = plot(comp.UIAxes_V_H, 0, 0, 'ko', 'MarkerSize', 10, 'MarkerFaceColor', 'k'); % Rear wheel
            
            % Draw road bump
            bump_x = [start_distance, start_distance + (a-b_bump)/2, start_distance + (a+b_bump)/2, start_distance + a];
            bump_y = [0, c, c, 0];
            fill(comp.UIAxes_V_H, bump_x, bump_y, 'g');
            
            for i = 1:length(T)
                % Car position and angle
                x_pos = v * T(i);
                y_pos = X(i, 1);
                angle = X(i, 3);
                
                % Calculate the rotation matrix
                R = [cos(angle) -sin(angle); sin(angle) cos(angle)];
                
                % Car corners positions
                car_corners = [-car_length/2, -car_width/2;
                               car_length/2, -car_width/2;
                               car_length/2, car_width/2;
                               -car_length/2, car_width/2]';
                           
                rotated_corners = R * car_corners;
                
                % Update car position
                set(car, 'XData', rotated_corners(1, :) + x_pos, 'YData', rotated_corners(2, :) + y_pos);
                
                % Update wheel positions
                front_wheel_pos = R * [b; 0] + [x_pos; y_pos];
                rear_wheel_pos = R * [-b; 0] + [x_pos; y_pos];
                set(front_wheel, 'XData', front_wheel_pos(1), 'YData', front_wheel_pos(2));
                set(rear_wheel, 'XData', rear_wheel_pos(1), 'YData', rear_wheel_pos(2));
                
                % Update plot
                drawnow;
                pause(0.01);
            end
        end

        % Button pushed function: clearAllPlot
        function clearAllPlotButtonPushed(comp, event)
            cla(comp.UIAxes_V_T);
            cla(comp.UIAxes_A_T);
            cla(comp.UIAxes_V_H);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1272 723];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.MSVSTSB = uilabel(comp.GridLayout);
            comp.MSVSTSB.Text = 'Modeling and Simulation of Vehicle Suspension on Trapezoidal Speed Bumps';
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.UIAxes_V_H = uiaxes(comp.GridLayout2);
            title(comp.UIAxes_V_H, 'Car Simulation over Road Bump')
            xlabel(comp.UIAxes_V_H, 'Horizontal Position (m)')
            ylabel(comp.UIAxes_V_H, 'Vertical Position (m)')
            zlabel(comp.UIAxes_V_H, 'Z')
            comp.UIAxes_A_T = uiaxes(comp.GridLayout2);
            title(comp.UIAxes_A_T, 'Pitch Angle of Car Body')
            xlabel(comp.UIAxes_A_T, 'Time (s)')
            ylabel(comp.UIAxes_A_T, 'Angular Displacement (degrees)')
            zlabel(comp.UIAxes_A_T, 'Z')
            comp.UIAxes_V_T = uiaxes(comp.GridLayout2);
            title(comp.UIAxes_V_T, 'Vertical Displacement of Car Body')
            xlabel(comp.UIAxes_V_T, 'Time (s)')
            ylabel(comp.UIAxes_V_T, 'Vertical Displacement of Car Body (m)')
            zlabel(comp.UIAxes_V_T, 'Z')
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.GridLayout5 = uigridlayout(comp.GridLayout3);
            comp.SimulationinformationLabel = uilabel(comp.GridLayout5);
            comp.SimulationinformationLabel.Text = 'Simulation information';
            comp.GridLayout6 = uigridlayout(comp.GridLayout5);
            comp.MassofthecarLabel = uilabel(comp.GridLayout6);
            comp.MassofthecarLabel.Text = 'Mass of the Car (kg)';
            comp.info_m = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_m.Value = 1500;
            comp.MomentofinertiakgmLabel = uilabel(comp.GridLayout6);
            comp.MomentofinertiakgmLabel.Text = 'Moment of inertia (kg.m²)';
            comp.info_I = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_I.Value = 3000;
            comp.KFrontNmEditFieldLabel = uilabel(comp.GridLayout6);
            comp.KFrontNmEditFieldLabel.Text = 'K-Front (N/m)';
            comp.info_k1 = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_k1.Value = 80000;
            comp.KRearNmEditFieldLabel = uilabel(comp.GridLayout6);
            comp.KRearNmEditFieldLabel.Text = 'K-Rear (N/m)';
            comp.info_k2 = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_k2.Value = 80000;
            comp.LargerbasemEditFieldLabel = uilabel(comp.GridLayout6);
            comp.LargerbasemEditFieldLabel.Text = 'Larger base (m)';
            comp.info_a = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_a.Value = 2;
            comp.CFrontNsmEditFieldLabel = uilabel(comp.GridLayout6);
            comp.CFrontNsmEditFieldLabel.Text = 'C-Front (N.s/m)';
            comp.info_c1 = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_c1.Value = 3500;
            comp.SmallerbasemEditFieldLabel = uilabel(comp.GridLayout6);
            comp.SmallerbasemEditFieldLabel.Text = 'Smaller base (m)';
            comp.info_b_bump = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_b_bump.Value = 1;
            comp.CRearNsmEditField_2Label = uilabel(comp.GridLayout6);
            comp.CRearNsmEditField_2Label.Text = 'C-Rear (N.s/m)';
            comp.info_c2 = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_c2.Value = 3500;
            comp.HeightmEditFieldLabel = uilabel(comp.GridLayout6);
            comp.HeightmEditFieldLabel.Text = 'Height (m)';
            comp.info_c = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_c.Value = 0.1;
            comp.DistancebetweenthewheelsmEditFieldLabel = uilabel(comp.GridLayout6);
            comp.DistancebetweenthewheelsmEditFieldLabel.Text = 'Distance between the wheels (m)';
            comp.info_d = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_d.Value = 2.5;
            comp.DistancefrontwheeltobumpmEditFieldLabel = uilabel(comp.GridLayout6);
            comp.DistancefrontwheeltobumpmEditFieldLabel.Text = 'Distance front wheel to bump (m)';
            comp.info_start_distance = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_start_distance.Value = 5;
            comp.VCarkmhEditFieldLabel = uilabel(comp.GridLayout6);
            comp.VCarkmhEditFieldLabel.Text = 'V-Car (km/h)';
            comp.info_v_m_s = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_v_m_s.Value = 10;
            comp.SimulationTimesEditFieldLabel = uilabel(comp.GridLayout6);
            comp.SimulationTimesEditFieldLabel.Text = 'Simulation Time (s)';
            comp.info_simulation_time = uieditfield(comp.GridLayout6, 'numeric');
            comp.info_simulation_time.Value = 5;
            comp.BumpInformationLabel = uilabel(comp.GridLayout6);
            comp.BumpInformationLabel.Text = 'Bump Information';
            comp.GridLayout4_2 = uigridlayout(comp.GridLayout3);
            comp.runPlotBtn = uibutton(comp.GridLayout4_2, 'push');
            comp.runPlotBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @runPlotBtnButtonPushed, true);
            comp.runPlotBtn.Text = 'Run';
            comp.clearAllPlot = uibutton(comp.GridLayout4_2, 'push');
            comp.clearAllPlot.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @clearAllPlotButtonPushed, true);
            comp.clearAllPlot.Text = 'Clear';
        end
    end
end