classdef comp1 < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        NodesTab                       matlab.ui.container.Tab
        nodeGridLayout                 matlab.ui.container.GridLayout
        n_Btn_GridLayout               matlab.ui.container.GridLayout
        removeRowToNode                matlab.ui.control.Button
        applyNodesBtn                  matlab.ui.control.Button
        addRowToNode                   matlab.ui.control.Button
        nodesTable                     matlab.ui.control.Table
        NodesDataLabel                 matlab.ui.control.Label
        ElementsTab                    matlab.ui.container.Tab
        elementGridLayout              matlab.ui.container.GridLayout
        e_Btn_GridLayout               matlab.ui.container.GridLayout
        removeRowToElement             matlab.ui.control.Button
        applyElementsBtn               matlab.ui.control.Button
        addRowToElement                matlab.ui.control.Button
        elementsTable                  matlab.ui.control.Table
        ElementsDataLabel              matlab.ui.control.Label
        ForcesTab                      matlab.ui.container.Tab
        forceGridLayout                matlab.ui.container.GridLayout
        f_Btn_GridLayout               matlab.ui.container.GridLayout
        removeRowToForce               matlab.ui.control.Button
        applyForcesBtn                 matlab.ui.control.Button
        addRowToForce                  matlab.ui.control.Button
        forcesTable                    matlab.ui.control.Table
        ForcesDataLabel                matlab.ui.control.Label
        SupportsTab                    matlab.ui.container.Tab
        supportGridLayout              matlab.ui.container.GridLayout
        s_Btn_GridLayout               matlab.ui.container.GridLayout
        removeRowToSupports            matlab.ui.control.Button
        runBtn                         matlab.ui.control.Button
        addRowToSupports               matlab.ui.control.Button
        supportsTable                  matlab.ui.control.Table
        SupportsDataLabel              matlab.ui.control.Label
        LogTab                         matlab.ui.container.Tab
        LogTextArea                    matlab.ui.control.TextArea
        LogTextAreaLabel               matlab.ui.control.Label
        StaticTrussAnalysisFEM3DLabel  matlab.ui.control.Label
        StructureDataLabel             matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = public)
        broken logical = false;
        elementData
        element_angles double
        element_lengths double
        elementsCounter double = 1;
        firstElementEdit logical = 1;
        firstForceEdit = 1;
        forcesCounter double = 1;
        forcesData
        newNodesLoc double
        nodesCounter double = 1;
        nodesData double
        row (1,3) double = 0;
        stress_uti_all (100,1) double
        supportsCounter double = 1;
        supportsData
    end
    
    
    methods (Access = private)
      
    end
    
methods (Access = public)
    function megapascals = pascalsToMegapascals(pascals)
        megapascals = pascals / 1e6;
    end
    function microMetres = meterTomicroMetres(meter)
        microMetres = meter * 1e6;
    end

    function results = applyNodesToPloat(comp)
        nodes = cell2mat(comp.nodesTable.Data);
        scatter3(comp.UIAxes, nodes(:, 2), nodes(:, 3), nodes(:, 4), 120, [0.8500 0.3250 0.0980], 'filled');

        text(comp.UIAxes, nodes(:, 2), nodes(:, 3), nodes(:, 4) + .23, arrayfun(@(x) num2str(x), (1:size(nodes, 1))', 'UniformOutput', false), 'FontSize', 15, 'Color', "#279501");
        results = nodes;
    end

    function [x_node, y_node, z_node] = getNodePos(comp, node, newPos)
        if newPos
            new_nodes = comp.newNodesLoc;
            x_node = new_nodes(node, 2);
            y_node = new_nodes(node, 3);
            z_node = new_nodes(node, 4);
            return
        end
        nodes = cell2mat(comp.nodesTable.Data);
        x_node = nodes(node, 2);
        y_node = nodes(node, 3);
        z_node = nodes(node, 4);
    end

    function [node_1, node_2] = getNodesByElement(comp, element)
        data = cell2mat(comp.elementsTable.Data);
        node_1 = data(element, 2);
        node_2 = data(element, 3);
    end

    function [support_node, Dx, Dy, Dz, num] = getSupportInfo(comp, i, num)
        support_node = comp.supportsData(i, 2);
        Dx = comp.supportsData(i, 3);
        Dy = comp.supportsData(i, 4);
        Dz = comp.supportsData(i, 5);
    
        if ~(num); return; end;

        if Dx == 1; num(1, (3 * support_node) - 2) = (3 * support_node) - 2; end;
        if Dy == 1; num(1, (3 * support_node) - 1) = (3 * support_node) - 1; end;
        if Dz == 1; num(1, (3 * support_node)) = (3 * support_node); end;
    end

    function results = applyElementsToPloat(comp)
        hold(comp.UIAxes, 'on');

        if comp.firstElementEdit == 1; comp.firstElementEdit = 0; else; delete(findobj(comp.UIAxes, 'Tag', 'firstElData')); end;
        for i = 1:size(comp.elementData, 1)
            [node1, node2] = getNodesByElement(comp, i);
            [x1, y1, z1] = getNodePos(comp, node1, false);
            [x2, y2, z2] = getNodePos(comp, node2, false);
                 
            plot3(comp.UIAxes, [x1, x2], [y1, y2], [z1, z2], 'Color', [0 0.4470 0.7410], 'LineWidth', 4, 'Tag', 'firstElData');

            mid_x = (x1 + x2) / 2;
            mid_y = (y1 + y2) / 2;
            mid_z = (z1 + z2) / 2;

            text(comp.UIAxes, mid_x, mid_y, mid_z, sprintf('%d', i), 'FontSize', 12, 'Color', "#363636", 'Tag', 'firstElData');
        end
    end
    function results = applyForcesToPloat(comp)
        hold(comp.UIAxes, 'on');        

        if comp.firstForceEdit == 1; comp.firstForceEdit = 0; else; delete(findobj(comp.UIAxes, 'Tag', 'firstForceData')); end;
        scale = .05;

        for i = 1:size(comp.forcesData, 1)
            x = comp.nodesData(comp.forcesData(i, 2), 2);
            y = comp.nodesData(comp.forcesData(i, 2), 3);
            z = comp.nodesData(comp.forcesData(i, 2), 4);

            u = comp.forcesData(i, 3);
            v = comp.forcesData(i, 4);
            w = comp.forcesData(i, 5);

            if (u > 0); u = 1; elseif (u < 0); u = -1; end;
            if (v > 0); v = 1; elseif (v < 0); v = -1; end;
            if (w > 0); w = 1; elseif (w < 0); w = -1; end;

            resultant_force = [u , v , w];

            quiver3(comp.UIAxes, x, y, z, resultant_force(1)*scale, resultant_force(2)*scale, resultant_force(3)*scale, 'k', 'LineWidth', 2, 'Tag', 'firstForceData');
        end
    end
    function results = applySupportsToPloat(comp)
        hold(comp.UIAxes, 'on');
        for i = 1:size(comp.supportsData, 1)
            [support_node, Dx, Dy, Dz, num] = getSupportInfo(comp, i, false);
            [x, y, z] = getNodePos(comp, support_node, false);
            
            if Dx == 1 && Dy == 1 && Dz == 1
                color = 'k';
            elseif Dx == 0 && Dy == 0 && Dz == 0
                color = 'b';
            elseif Dx == 1 && Dy == 0 && Dz == 0
                color = 'g';
            elseif Dx == 0 && Dy == 1 && Dz == 0
                color = 'c';
            elseif Dx == 0 && Dy == 0 && Dz == 1
                color = 'm';
            elseif Dx == 1 && Dy == 1 && Dz == 0
                color = 'w';
            elseif Dx == 0 && Dy == 1 && Dz == 1
                color = [0.4660 0.6740 0.1880];
            elseif Dx == 1 && Dy == 0 && Dz == 1
                color = [0.8500 0.3250 0.0980]	;
            end
            scatter3(comp.UIAxes, x, y, z, 120, color, 'filled');
        end
    end
    function [element_lengths, element_angles_x, element_angles_y, element_angles_z, area, young_modulus, delta_f_mat] = getElementInformation(comp, i, n_1, n_2)
        [x_1, y_1, z_1] = getNodePos(comp, n_1, false);
        [x_2, y_2, z_2] = getNodePos(comp, n_2, false);
        delta_x = x_2 - x_1;
        delta_y = y_2 - y_1;
        delta_z = z_2 - z_1;
        
        element_lengths = sqrt((delta_x^2) + (delta_y^2) + (delta_z^2));
        element_angles_x = acos(delta_x/element_lengths) * (180/pi);
        element_angles_y = acos(delta_y/element_lengths) * (180/pi);
        element_angles_z = acos(delta_z/element_lengths) * (180/pi);
        
        area = comp.elementData(i, 4);
        young_modulus = comp.elementData(i, 5);
        
        x = element_angles_x * (pi/180);
        y = element_angles_y * (pi/180);
        z = element_angles_z * (pi/180);
        c_o_s_x = cos(x);
        c_o_s_y = cos(y);
        c_o_s_z = cos(z);
        delta_f_mat = [c_o_s_x*c_o_s_x c_o_s_x*c_o_s_y c_o_s_x*c_o_s_z ; c_o_s_y*c_o_s_x c_o_s_y*c_o_s_y c_o_s_y*c_o_s_z ; c_o_s_z*c_o_s_x c_o_s_z*c_o_s_y c_o_s_z*c_o_s_z];
    end
    function [force_node, fx, fy, fz] = getForceInfo(comp, i)
        force_node = comp.forcesData(i, 2);
        force_len = comp.forcesData(i, 3);
        
        %force_angle_x = data{1, 1}.forces(i, 3) / 180 * pi;
        %force_angle_y = data{1, 1}.forces(i, 4) / 180 * pi;
        %force_angle_z = data{1, 1}.forces(i, 5) / 180 * pi;
        
        %fx = force_len * cos(force_angle_x);
        %fy = force_len * cos(force_angle_y);
        %fz = force_len * cos(force_angle_z);
        
        fx = comp.forcesData(i, 3);
        fy = comp.forcesData(i, 4);
        fz = comp.forcesData(i, 5);
    end
    function [delta_len, force, stress, strain, stress_utilisation] = getResultByElement(comp, i, delta_lenData, forceData, stressData)
        yield_stress = comp.elementData(i, 6);
        el_len = comp.element_lengths(i);

        delta_len = delta_lenData * 1e6;
        force = forceData;
        stress = stressData / 1e6;
        strain = (delta_len) / el_len;
        stress_utilisation = abs(stressData / yield_stress) * 100;
    end

    function analysisHandler(comp)
        element_res = cell(size(comp.supportsData, 1), 1);
        comp.newNodesLoc = comp.nodesData;
        comp.element_lengths = zeros(size(comp.elementData, 1), 1);
        comp.element_angles = zeros(size(comp.elementData, 1), 3);


        k_data = zeros(3 * size(comp.nodesData, 1));
        k0_data = k_data;

        for i = 1:size(comp.elementData, 1)
            [n_1, n_2] = getNodesByElement(comp, i);
            [el_lengths, element_angles_x, element_angles_y, element_angles_z, area, young_modulus, delta_f_mat] = getElementInformation(comp, i, n_1, n_2);
            
            comp.element_lengths(i) = el_lengths;
            
            comp.element_angles(i, 1) = element_angles_x;
            comp.element_angles(i, 2) = element_angles_y;
            comp.element_angles(i, 3) = element_angles_z;

            k{i} = area * young_modulus / el_lengths * [delta_f_mat -delta_f_mat; -delta_f_mat delta_f_mat];

            [n_1, n_2] = getNodesByElement(comp, i);

            k0_data(3 * n_1 - 2:3 * n_1, 3 * n_1 - 2:3 * n_1) = k{i}(1:3, 1:3);
            k0_data(3 * n_1 - 2:3 * n_1, 3 * n_2 - 2:3 * n_2) = k{i}(1:3, 4:6);
            k0_data(3 * n_2 - 2:3 * n_2, 3 * n_1 - 2:3 * n_1) = k{i}(4:6, 1:3);
            k0_data(3 * n_2 - 2:3 * n_2, 3 * n_2 - 2:3 * n_2) = k{i}(4:6, 4:6);
            
            k_data = k_data + k0_data;
            k0_data(:, :) = 0;
        end

        force_0 = zeros(3 * size(comp.nodesData, 1), 1);
        for i = 1:size(comp.forcesData, 1)
            [force_node, fx, fy, fz] = getForceInfo(comp, i);
            force_0((3 * force_node) - 2:(3 * force_node), 1) = [fx; fy; fz];
        end

        num_data = [];
        for i = 1:size(comp.supportsData, 1)
            [support_node, Dx, Dy, Dz, num] = getSupportInfo(comp, i, num_data);
            num_data = num(num ~= 0);
            uu_zero = [num_data(:)];
        end


        k_small_data = k_data;
        forces_data = force_0;
        k_small_data(:, uu_zero) = [];
        k_small_data(uu_zero, :) = [];
        forces_data(uu_zero, :) = [];
        u_all_data = (k_small_data^-1) * forces_data;
        uu_all = 1:(3 * size(comp.nodesData, 1));
        uu_nonzero = uu_all;
        uu_nonzero(uu_zero) = [];
        u_data(uu_all, 1) = 0;
        u_data(uu_nonzero, 1) = u_all_data;
        force = k_data * u_data;

        for i = 1:size(comp.elementData, 1)
            c_o_s_x = cos(comp.element_angles(i, 1)*pi/180);
            c_o_s_y = cos(comp.element_angles(i, 2)*pi/180);
            c_o_s_z = cos(comp.element_angles(i, 3)*pi/180);

            [n_1, n_2] = getNodesByElement(comp, i);

            delta = [-c_o_s_x -c_o_s_y -c_o_s_z c_o_s_x c_o_s_y c_o_s_z] * [u_data((3*n_1) - 2); u_data((3*n_1) - 1); u_data(3*n_1); u_data((3*n_2) - 2); u_data((3*n_2) - 1); u_data(3*n_2)];

            n1_new_loc_x = u_data((3 * n_1) - 2);
            n1_new_loc_y = u_data((3 * n_1) - 1);
            n1_new_loc_z = u_data(3 * n_1);

            n2_new_loc_x = u_data((3 * n_2) - 2);
            n2_new_loc_y = u_data((3 * n_2) - 1);
            n2_new_loc_z = u_data(3 * n_2);

            comp.newNodesLoc(n_1, :) = comp.newNodesLoc(n_1, :) + [0, n1_new_loc_x, n1_new_loc_y, n1_new_loc_z];
            comp.newNodesLoc(n_2, :) = comp.newNodesLoc(n_2, :) + [0, n2_new_loc_x, n2_new_loc_y, n2_new_loc_z];

            area = comp.elementData(i, 4);
            young_modulus = comp.elementData(i, 5);

            p = area * young_modulus / comp.element_lengths(i) * delta;

            delta_len_res = delta;
            force_res = p;
            stress_res = p/area;

            [delta_len, force, stress, strain, stress_utilisation] = getResultByElement(comp, i, delta_len_res, force_res, stress_res);
            comp.stress_uti_all(i) = stress_utilisation;
            stress_utilisation_res = stress_utilisation;
            strain_res = strain;

            logText = sprintf(' -------------------- Element (%g) [n: %g,%g] --------------------\n   Delta: %g (um) \n   Force: %g (N) \n   Stress: %g (MPa) \n   Strain: %g \n   Stress Utilisation: %g%%  \n', i, n_1, n_2, delta_len, force, stress, strain, stress_utilisation);
            logTextCell = cellstr(logText);
            currentText = comp.LogTextArea.Value;
            newText = [currentText; logTextCell];
            comp.LogTextArea.Value = newText;
        end
        createFinalStr(comp);
    end
    function results = createFinalStr(comp)
        hold(comp.UIAxes, 'on');

        delete(findobj(comp.UIAxes, 'Tag', 'firstElData'));
        for i = 1:size(comp.elementData, 1)
            [node1, node2] = getNodesByElement(comp, i);
            [x1, y1, z1] = getNodePos(comp, node1, false);
            [x2, y2, z2] = getNodePos(comp, node2, false);
            [new_x1, new_y1, new_z1] = getNodePos(comp, node1, true);
            [new_x2, new_y2, new_z2] = getNodePos(comp, node2, true);

            broken = false;
            if comp.stress_uti_all(i) > 100; color = [0.6350 0.0780 0.1840]; broken = true; else; color = [0 0.4470 0.7410]; end;
    
            plot3(comp.UIAxes, [new_x1, new_x2], [new_y1, new_y2], [new_z1, new_z2], 'Color', "magenta", 'LineWidth', 1);
            plot3(comp.UIAxes, [x1, x2], [y1, y2], [z1, z2], 'Color', color, 'LineWidth', 4, 'Tag', 'finalElData');

            mid_x = (x1 + x2) / 2;
            mid_y = (y1 + y2) / 2;
            mid_z = (z1 + z2) / 2;

            text(comp.UIAxes, mid_x, mid_y, mid_z, sprintf('%d', i), 'FontSize', 12, 'Color', "#363636", 'Tag', 'finalElData');
        end
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: addRowToNode
        function addRowToNodeButtonPushed(comp, event)
            r_data = {comp.nodesCounter, 0, 0, 0};
            comp.nodesTable.Data = [comp.nodesTable.Data; r_data];
            comp.nodesCounter = comp.nodesCounter + 1;
        end

        % Button pushed function: addRowToElement
        function addRowToElementButtonPushed(comp, event)
            r_data = {comp.elementsCounter, 0, 0, 0, 0, 0};
            comp.elementsTable.Data = [comp.elementsTable.Data; r_data];
            comp.elementsCounter = comp.elementsCounter + 1;
        end

        % Button pushed function: addRowToForce
        function addRowToForceButtonPushed(comp, event)
            r_data = {comp.forcesCounter, 0, 0, 0, 0};
            comp.forcesTable.Data = [comp.forcesTable.Data; r_data];
            comp.forcesCounter = comp.forcesCounter + 1;
        end

        % Button pushed function: addRowToSupports
        function addRowToSupportsButtonPushed(comp, event)
            r_data = {comp.supportsCounter, 0, 0, 0, 0};
            comp.supportsTable.Data = [comp.supportsTable.Data; r_data];
            comp.supportsCounter = comp.supportsCounter + 1;
        end

        % Button pushed function: removeRowToNode
        function removeRowToNodeButtonPushed(comp, event)
            if comp.nodesCounter == 1; return; end;
            comp.nodesTable.Data = comp.nodesTable.Data(1:end-1, :);
            comp.nodesCounter = comp.nodesCounter - 1;
        end

        % Button pushed function: removeRowToElement
        function removeRowToElementButtonPushed(comp, event)
            if comp.elementsCounter == 1; return; end;
            comp.elementsTable.Data = comp.elementsTable.Data(1:end-1, :);
            comp.elementsCounter = comp.elementsCounter - 1;
        end

        % Button pushed function: removeRowToForce
        function removeRowToForceButtonPushed(comp, event)
            if comp.forcesCounter == 1; return; end;
            comp.forcesTable.Data = comp.forcesTable.Data(1:end-1, :);
            comp.forcesCounter = comp.forcesCounter - 1;
        end

        % Button pushed function: removeRowToSupports
        function removeRowToSupportsButtonPushed(comp, event)
            if comp.supportsCounter == 1; return; end;
            comp.supportsTable.Data = comp.supportsTable.Data(1:end-1, :);
            comp.supportsCounter = comp.supportsCounter - 1;
        end

        % Button pushed function: applyNodesBtn
        function applyNodesBtnButtonPushed(comp, event)
            applyNodesToPloat(comp);
            comp.nodesData = cell2mat(comp.nodesTable.Data);
        end

        % Button pushed function: applyElementsBtn
        function applyElementsBtnButtonPushed(comp, event)
            comp.elementData = cell2mat(comp.elementsTable.Data);
            applyElementsToPloat(comp);
            
            comp.addRowToNode.Enable = "off";
            comp.removeRowToNode.Enable = "off";
            comp.applyNodesBtn.Enable = "off";
            comp.nodesTable.Enable = "inactive";
        end

        % Button pushed function: applyForcesBtn
        function applyForcesBtnButtonPushed(comp, event)
            comp.forcesData = cell2mat(comp.forcesTable.Data);
            applyForcesToPloat(comp);

            comp.addRowToElement.Enable = "off";
            comp.removeRowToElement.Enable = "off";
            comp.applyElementsBtn.Enable = "off";
            comp.elementsTable.Enable = "inactive";
        end

        % Button pushed function: runBtn
        function runBtnButtonPushed(comp, event)
            comp.supportsData = cell2mat(comp.supportsTable.Data);

            applySupportsToPloat(comp);
            
            comp.addRowToForce.Enable = "off";
            comp.removeRowToForce.Enable = "off";
            comp.applyForcesBtn.Enable = "off";
            comp.forcesTable.Enable = "inactive";
            comp.addRowToSupports.Enable = "off";
            comp.removeRowToSupports.Enable = "off";
            comp.runBtn.Enable = "off";
            comp.supportsTable.Enable = "inactive";

            analysisHandler(comp);
        end

        % Cell edit callback: supportsTable
        function supportsTableCellEdit(comp, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1210 734];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            title(comp.UIAxes, 'Structure')
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes.Tag = 'strPlotData';
            comp.StructureDataLabel = uilabel(comp.GridLayout);
            comp.StructureDataLabel.Interpreter = 'tex';
            comp.StructureDataLabel.Tag = 'label';
            comp.StructureDataLabel.Text = 'Structure Data';
            comp.StaticTrussAnalysisFEM3DLabel = uilabel(comp.GridLayout);
            comp.StaticTrussAnalysisFEM3DLabel.Interpreter = 'tex';
            comp.StaticTrussAnalysisFEM3DLabel.Tag = 'label';
            comp.StaticTrussAnalysisFEM3DLabel.Text = 'Static Truss Analysis FEM - 3D';
            comp.TabGroup2 = uitabgroup(comp.GridLayout);
            comp.NodesTab = uitab(comp.TabGroup2);
            comp.NodesTab.Title = 'Nodes';
            comp.nodeGridLayout = uigridlayout(comp.NodesTab);
            comp.NodesDataLabel = uilabel(comp.nodeGridLayout);
            comp.NodesDataLabel.Interpreter = 'tex';
            comp.NodesDataLabel.Tag = 'label';
            comp.NodesDataLabel.Text = 'Nodes Data';
            comp.nodesTable = uitable(comp.nodeGridLayout);
            comp.nodesTable.ColumnName = {'Node Number'; 'X'; 'Y'; 'Z'};
            comp.nodesTable.RowName = {};
            comp.nodesTable.ColumnSortable = [true false false false];
            comp.nodesTable.ColumnEditable = [false true true true];
            comp.n_Btn_GridLayout = uigridlayout(comp.nodeGridLayout);
            comp.addRowToNode = uibutton(comp.n_Btn_GridLayout, 'push');
            comp.addRowToNode.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addRowToNodeButtonPushed, true);
            comp.addRowToNode.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.addRowToNode.Text = 'Add Row';
            comp.applyNodesBtn = uibutton(comp.n_Btn_GridLayout, 'push');
            comp.applyNodesBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @applyNodesBtnButtonPushed, true);
            comp.applyNodesBtn.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.applyNodesBtn.Text = 'Apply';
            comp.removeRowToNode = uibutton(comp.n_Btn_GridLayout, 'push');
            comp.removeRowToNode.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @removeRowToNodeButtonPushed, true);
            comp.removeRowToNode.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.removeRowToNode.Text = 'Remove Row';
            comp.ElementsTab = uitab(comp.TabGroup2);
            comp.ElementsTab.Title = 'Elements';
            comp.elementGridLayout = uigridlayout(comp.ElementsTab);
            comp.ElementsDataLabel = uilabel(comp.elementGridLayout);
            comp.ElementsDataLabel.Interpreter = 'tex';
            comp.ElementsDataLabel.Tag = 'label';
            comp.ElementsDataLabel.Text = 'Elements Data';
            comp.elementsTable = uitable(comp.elementGridLayout);
            comp.elementsTable.ColumnName = {'El Num'; 'Node 1'; 'Node 2'; 'A(m²)'; 'E(Pa)'; 'YS(Pa)'};
            comp.elementsTable.RowName = {};
            comp.elementsTable.ColumnSortable = [true false false false false false];
            comp.elementsTable.ColumnEditable = [false true true true true true];
            comp.e_Btn_GridLayout = uigridlayout(comp.elementGridLayout);
            comp.addRowToElement = uibutton(comp.e_Btn_GridLayout, 'push');
            comp.addRowToElement.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addRowToElementButtonPushed, true);
            comp.addRowToElement.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.addRowToElement.Text = 'Add Row';
            comp.applyElementsBtn = uibutton(comp.e_Btn_GridLayout, 'push');
            comp.applyElementsBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @applyElementsBtnButtonPushed, true);
            comp.applyElementsBtn.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.applyElementsBtn.Text = 'Apply';
            comp.removeRowToElement = uibutton(comp.e_Btn_GridLayout, 'push');
            comp.removeRowToElement.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @removeRowToElementButtonPushed, true);
            comp.removeRowToElement.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.removeRowToElement.Text = 'Remove Row';
            comp.ForcesTab = uitab(comp.TabGroup2);
            comp.ForcesTab.Title = 'Forces';
            comp.forceGridLayout = uigridlayout(comp.ForcesTab);
            comp.ForcesDataLabel = uilabel(comp.forceGridLayout);
            comp.ForcesDataLabel.Interpreter = 'tex';
            comp.ForcesDataLabel.Tag = 'label';
            comp.ForcesDataLabel.Text = 'Forces Data';
            comp.forcesTable = uitable(comp.forceGridLayout);
            comp.forcesTable.ColumnName = {'Force Number'; 'Node Number'; 'F(N) - X'; 'F(N) - Y'; 'F(N) - Z'};
            comp.forcesTable.RowName = {};
            comp.forcesTable.ColumnSortable = [true false false false false];
            comp.forcesTable.ColumnEditable = [false true true true true];
            comp.f_Btn_GridLayout = uigridlayout(comp.forceGridLayout);
            comp.addRowToForce = uibutton(comp.f_Btn_GridLayout, 'push');
            comp.addRowToForce.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addRowToForceButtonPushed, true);
            comp.addRowToForce.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.addRowToForce.Text = 'Add Row';
            comp.applyForcesBtn = uibutton(comp.f_Btn_GridLayout, 'push');
            comp.applyForcesBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @applyForcesBtnButtonPushed, true);
            comp.applyForcesBtn.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.applyForcesBtn.Text = 'Apply';
            comp.removeRowToForce = uibutton(comp.f_Btn_GridLayout, 'push');
            comp.removeRowToForce.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @removeRowToForceButtonPushed, true);
            comp.removeRowToForce.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.removeRowToForce.Text = 'Remove Row';
            comp.SupportsTab = uitab(comp.TabGroup2);
            comp.SupportsTab.Title = 'Supports';
            comp.supportGridLayout = uigridlayout(comp.SupportsTab);
            comp.SupportsDataLabel = uilabel(comp.supportGridLayout);
            comp.SupportsDataLabel.Interpreter = 'tex';
            comp.SupportsDataLabel.Tag = 'label';
            comp.SupportsDataLabel.Text = 'Supports Data';
            comp.supportsTable = uitable(comp.supportGridLayout);
            comp.supportsTable.ColumnName = {'Support Number'; 'Node Number'; 'Dx'; 'Dy'; 'Dz'};
            comp.supportsTable.RowName = {};
            comp.supportsTable.ColumnSortable = [true false false false false];
            comp.supportsTable.ColumnEditable = [false true true true true];
            comp.supportsTable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @supportsTableCellEdit, true);
            comp.s_Btn_GridLayout = uigridlayout(comp.supportGridLayout);
            comp.addRowToSupports = uibutton(comp.s_Btn_GridLayout, 'push');
            comp.addRowToSupports.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addRowToSupportsButtonPushed, true);
            comp.addRowToSupports.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.addRowToSupports.Text = 'Add Row';
            comp.runBtn = uibutton(comp.s_Btn_GridLayout, 'push');
            comp.runBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @runBtnButtonPushed, true);
            comp.runBtn.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.runBtn.Text = 'Apply & Run';
            comp.removeRowToSupports = uibutton(comp.s_Btn_GridLayout, 'push');
            comp.removeRowToSupports.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @removeRowToSupportsButtonPushed, true);
            comp.removeRowToSupports.Tooltip = {'Apply the data entered in the table above to perform calculations.'};
            comp.removeRowToSupports.Text = 'Remove Row';
            comp.LogTab = uitab(comp.TabGroup2);
            comp.LogTab.Title = 'Log';
            comp.LogTextAreaLabel = uilabel(comp.LogTab);
            comp.LogTextAreaLabel.Text = 'Log';
            comp.LogTextArea = uitextarea(comp.LogTab);
            comp.LogTextArea.Editable = 'off';
        end
    end
end